classdef SlugPen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure_Main                    matlab.ui.Figure
        grid_MainFigure                matlab.ui.container.GridLayout
        button_CommandWindowArrow      matlab.ui.control.Button
        panel_PlotWindow               matlab.ui.container.Panel
        grid_PlotWindow                matlab.ui.container.GridLayout
        grid_DiscardSensors            matlab.ui.container.GridLayout
        button_RemoveSensors           matlab.ui.control.Button
        image_tiltlabel                matlab.ui.control.Image
        image_accellabel               matlab.ui.control.Image
        image_depthlabel               matlab.ui.control.Image
        image_templabel                matlab.ui.control.Image
        panel_Thermistors              matlab.ui.container.Panel
        panel_ProcessingInfoLabels     matlab.ui.container.Panel
        grid_ProcessingInfoLabels      matlab.ui.container.GridLayout
        label_ProcessingTime           matlab.ui.control.Label
        label_Longitude                matlab.ui.control.Label
        label_Latitude                 matlab.ui.control.Label
        label_Penetration              matlab.ui.control.Label
        label_Station                  matlab.ui.control.Label
        label_Cruise                   matlab.ui.control.Label
        edit_CruiseTitle               matlab.ui.control.Label
        edit_ProcessingTimeTitle       matlab.ui.control.Label
        edit_LongitudeTitle            matlab.ui.control.Label
        edit_LatitudeTitle             matlab.ui.control.Label
        edit_PenetrationTitle          matlab.ui.control.Label
        edit_StationTitle              matlab.ui.control.Label
        panel_Top                      matlab.ui.container.Panel
        panel_TopLabels                matlab.ui.container.Panel
        grid_TopLabels                 matlab.ui.container.GridLayout
        label_currentpathfull          matlab.ui.control.Label
        label_CurrentPath              matlab.ui.control.Label
        label_SlugHeat                 matlab.ui.control.Label
        label_Time                     matlab.ui.control.Label
        axis_raw                       matlab.ui.control.UIAxes
        axis_depth                     matlab.ui.control.UIAxes
        axis_accel                     matlab.ui.control.UIAxes
        axis_tilt                      matlab.ui.control.UIAxes
        panel_Data                     matlab.ui.container.Panel
        grid_data                      matlab.ui.container.GridLayout
        panel_info                     matlab.ui.container.Panel
        grid_datainfo                  matlab.ui.container.GridLayout
        edit_DecimateFilter            matlab.ui.control.NumericEditField
        edit_MeanFilter                matlab.ui.control.NumericEditField
        edit_MedianFilter              matlab.ui.control.NumericEditField
        label_FilterWinfowLength       matlab.ui.control.Label
        edit_NumberofTherm             matlab.ui.control.EditField
        NumberofThermistorsLabel       matlab.ui.control.Label
        button_LoadRaw                 matlab.ui.control.Button
        edit_pulse_W                   matlab.ui.control.EditField
        PulseWLabel                    matlab.ui.control.Label
        edit_decay                     matlab.ui.control.EditField
        DecayminEditFieldLabel         matlab.ui.control.Label
        edit_pulse_s                   matlab.ui.control.EditField
        PulsesecEditFieldLabel         matlab.ui.control.Label
        edit_logint                    matlab.ui.control.EditField
        LogIntervalsLabel              matlab.ui.control.Label
        edit_end_time                  matlab.ui.control.EditField
        EndTimeEditFieldLabel          matlab.ui.control.Label
        edit_start_time                matlab.ui.control.EditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        edit_start_date                matlab.ui.control.EditField
        StartDateEditFieldLabel        matlab.ui.control.Label
        edit_filename                  matlab.ui.control.EditField
        FilenameLabel                  matlab.ui.control.Label
        panel_selected                 matlab.ui.container.Panel
        grid_selected                  matlab.ui.container.GridLayout
        button_SetPen                  matlab.ui.control.Button
        checkbox_SelCal                matlab.ui.control.CheckBox
        edit_HeatPulse                 matlab.ui.control.EditField
        HeatPulseHEditFieldLabel       matlab.ui.control.Label
        edit_End                       matlab.ui.control.EditField
        EndPenetrationEEditFieldLabel  matlab.ui.control.Label
        edit_PenStart                  matlab.ui.control.EditField
        StartPenetrationPEditFieldLabel  matlab.ui.control.Label
        edit_EndEqm                    matlab.ui.control.EditField
        EndCalibrationPeriodW2Label    matlab.ui.control.Label
        edit__StartEqm                 matlab.ui.control.EditField
        StartCalibrationPeriodW1Label  matlab.ui.control.Label
        checkbox_UseHP                 matlab.ui.control.CheckBox
        button__ResetPen               matlab.ui.control.Button
        button_BeginSelections         matlab.ui.control.Button
        button_ResetSelection          matlab.ui.control.Button
        dropdown_Select                matlab.ui.control.DropDown
        panel_plotcontrols             matlab.ui.container.Panel
        grid_plotcontrols              matlab.ui.container.GridLayout
        checkbox_rawdec                matlab.ui.control.CheckBox
        checkbox_decp                  matlab.ui.control.CheckBox
        checkbox_tiltplot              matlab.ui.control.CheckBox
        checkbox_clnp                  matlab.ui.control.CheckBox
        checkbox_accelplot             matlab.ui.control.CheckBox
        checkbox_rawp                  matlab.ui.control.CheckBox
        checkbox_depthplot             matlab.ui.control.CheckBox
        label_datacheckboxes           matlab.ui.control.Label
        label_plotscheckboxes          matlab.ui.control.Label
        panel_SlugPen                  matlab.ui.container.Panel
        grid_SlugPen                   matlab.ui.container.GridLayout
        grid_UCSCimage                 matlab.ui.container.GridLayout
        image_UCSC                     matlab.ui.control.Image
        image_SlugPen                  matlab.ui.control.Image
        button_restart                 matlab.ui.control.Button
        button_quit                    matlab.ui.control.Button
    end


    properties (Access = public)

        % General program properties
        % ---------------------------
        Version = '1.0'  % Version of code
        Update = '03.12.2024' % date of last update by KD
       
        % Number of thermistors
        % -----------------------
        NoTherm % Total number of all sensors, including bottom water
        NoActiveTherm % Total number of ACTIVE sensors, including bottom water
        SensorsToUse % list of all ACTIVE sensors, including bottom water
       
        
        % Properties for vertical line selections 
        % ------------------------------------------
        VerticalLine
        roi
        x
        selTime
        selTime_Vector
        SE_VerticalLine
        EE_VerticalLine
        SP_VerticalLine
        HP_VerticalLine
        EP_VerticalLine
        SE_depthLine
        EE_depthLine
        SP_depthLine
        HP_depthLine
        EP_depthLine
        SE_accelLine
        EE_accelLine
        SP_accelLine
        HP_accelLine
        EP_accelLine
        SE_tiltLine
        EE_tiltLine
        SP_tiltLine
        HP_tiltLine
        EP_tiltLine

        % Previously hidden handles. 
        % Initial conditions all set to NaN in the start up function 
        % --------------------------------------------------------
        All_Zeros
        All_NaN
        H_Selected_raw 
        H_Selected_depth
        H_Selected_accel
        H_Selected_tilt
        H_Selected_raw_text
        H_Selected_depth_text
        H_Selected_accel_text
        H_Selected_tilt_text
        H_LegendSelect
        H_AxLims_Raw
        H_AxLims_Tilt
        H_AxLims_Depth
        H_AxLims_Acc
        H_NoThermistors

        % Plots
        % -----
        SelCalData 
        TempPlots % temperature plots

        % Flags
        % ------
        ErrorFlag
        CropFlag

        % Preveiously set to {'default'} 
        % ------------------------------
        Params 
        ParamsVals
        Parameters
       
  
        % Inital structures 
        % ------------------
        S_Axes = struct()
        S_AxLims = struct()
        S_Exe_Controls = struct()
        S_Selections = struct()
        S_Plot_Controls = struct()
        S_Selections_Lines = struct()
        S_VerticalLines = struct()
        S_FileInfo = struct()
        H = struct()
        DATA = struct() 

        % Penetration data variables
        % -------------------------
        Cruisename       
        StationName      
        Penetration      
        ProbeID         
        Datum           
        LoggerID         
        Latitude          
        Longitude         
        Tilt_mean        
        Depth_mean  

         % Auxillary Apps
         % --------------
         SetPenInfoApp
         SlugPen_CreatePenFileDialogBox % Dialog Box app for creating penfile

        % Data selections for raw, clean, and decimated with ROI
        % -------------------------------------------------------
        rawSelect
        clnSelect
        decSelect
        rawData
        clnData
        decData
        roiSelect 

        % Plot checkboxes
        % ---------------
        DepthPlot
        AccelPlot
        TiltPlot
        Checkboxes
        S_PlotCheckboxes % structure for checkboxes
        S_EditBoxes % structure for edit boxes

        % User input info for penetration file header
        % -----------------------------------------
        penetrationInfo 
        
        % Axes children
        % -------------
        C 

        % Variable to enable the create penfile button 
        % ----------------------------------------------
        PenSet 

        % Data
        % -----
        PulsePower % vector with power in Joules of each heat pulse fire
        PulseData % 0 if there is no heat pulse. 1 if there is a heat pulse 
        HeatPulsePens % array that has a 1 for all penetrations with a heat pulse and a 0 for all penetrations without a heat pulse
        DataType % used to choose wheter ot process raw, decimated raw, filtered, or decimated filtered data
        Traw
        TrawU
        Tcln
        TdecRaw
        Tdec
        timeDec
        Record
        Pitch
        Roll
        G
        Tilt
        Time

        % Outputs of PlotData
        % ---------------------
        BadSensors
        
        % Data structures
        % --------------
        dataloaded
        parsedtiming
        datauniquetimes
        decimateddata
    end 
     
    methods (Access = public)
        
        function setPenInfo(app, cruise, station, pen, sensor, ...
                datum, instrument, numsens, numactivesens, lat, lon, tilt, depth,figure_SetPenInfo)
        
            % ===================================================
            %% Called to set penetration metadata from user input.
            % ===================================================

            % Get all variables 
            % -------------------
            app.Cruisename      = cruise;
            app.StationName     = station;
            app.Penetration     = pen;
            app.ProbeID         = sensor;
            app.Datum           = datum;
            app.LoggerID        = instrument;
            app.NoTherm         = str2double(numsens);
            app.NoActiveTherm   = str2double(numactivesens);
            app.Latitude        = lat;
            app.Longitude       = lon;
            app.Tilt_mean       = tilt;
            app.Depth_mean      = depth;

            app.label_Cruise.Text = app.Cruisename;
            app.label_Station.Text = app.StationName;
            
            % Create .pen file
            % ------------------
            [app.HeatPulsePens,...
             app.DataType] ...
                = CreatePen(app.HeatPulsePens, ...
                    app.PulseData, ...
                    app.NoTherm, ...
                    figure_SetPenInfo, ...
                    app.figure_Main,...
                    app.DATA, ...
                    app.H, ...
                    app.PulsePower, ...
                    app.Cruisename, ...
                    app.StationName, ...
                    app.Penetration, ...
                    app.Latitude, ...
                    app.Longitude, ...
                    app.Datum, ...
                    app.Depth_mean, ...
                    app.Tilt_mean, ...
                    app.LoggerID, ...
                    app.ProbeID, ...
                    app.NoActiveTherm);

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% ==========================================================
%% Called automatically with generation of the application.
% ==========================================================
            
%% Initialize
            clc
            
            % Find images needed for GUI
            % ---------------------------
            app.image_SlugPen.ImageSource = [pwd, '/images/SlugPenLogo.png'];
            app.image_UCSC.ImageSource    = [pwd, '/images/UCSC-HydrogeologyLogo.jpg'];
            app.button_CommandWindowArrow.Icon = [pwd, '/images/arrowpen.png'];
            app.button_quit.Icon = [pwd, '/images/x.png'];
            
            % Reset structures
            % ----------------
            
            % Intialize Structure % 
            app.S_Axes = struct('Raw',app.axis_raw,'Depth',app.axis_depth,...
            'Tilt',app.axis_tilt, 'Accel', app.axis_accel); 
           
            % Structure Containing Axes Limits
            app.S_AxLims = struct('AxLims_Raw_X',[],...
            'AxLims_Raw_Y',[],...
            'AxLims_Depth_X',[],...
            'AxLims_Depth_Y',[],...
            'AxLims_Tilt_X',[], ...
            'AxLims_Tilt_Y',[], ...
            'AxLims_Acc_X',[], ...
            'AxLims_Acc_Y',[]);
           
            % Structure Containing Executable Control Handles and Filter Window Lengths
            app.S_Exe_Controls = struct('Load',app.button_restart,...
             'CreatePen', app.button_SetPen);
           
            % Structure Containing Handles for Selection Record Numbers
            app.S_Selections = struct('Select',app.dropdown_Select,...
            'Start_Eqm',app.edit__StartEqm,'End_Eqm',app.edit_EndEqm,'Start_Pen',...
             app.edit_PenStart,'Start_Heat',app.edit_HeatPulse,'End_Pen',app.edit_End);
           
            % Structure Containing Plot Control Handles and Quit
            app.S_Plot_Controls = struct('RawPlot',app.checkbox_rawp,...
                'RawDecimatedPlot',app.checkbox_rawdec,'CleanPlot',app.checkbox_clnp','DecimatedPlot',app.checkbox_decp,...
            'Quit',app.button_quit,'Legend',app.H_LegendSelect);
           
            %Structure Containing Handles for Selection Vertical Line Plotting
            app.S_Selections_Lines = struct('Raw',app.H_Selected_raw,'Depth',app.H_Selected_depth,...
            'Tilt',app.H_Selected_tilt,...
            'Raw_Text',app.H_Selected_raw_text,...
            'Depth_Text',app.H_Selected_depth_text,'Tilt_Text',app.H_Selected_tilt_text);
           
            app.S_VerticalLines = struct('Start_Eqm_Line', app.SE_VerticalLine, ...
            'End_Eqm_Line', app.EE_VerticalLine, 'Start_Pen_Line', app.SP_VerticalLine, ...
            'Start_Heat_Line', app.HP_VerticalLine, 'End_Pen_Line', app.EP_VerticalLine, ...
               'SE_depth',[], 'SE_accel',[], 'SE_tilt',[], ...
               'EE_depth',[], 'EE_accel',[],...
               'EE_tilt',[],'SP_depth',[],'SP_accel',[], ...
               'SP_tilt',[],'HP_depth',[], ...
               'HP_accel',[],'HP_tilt',[],'EP_depth',[], ...
               'EP_accel',[],'EP_tilt',[]);
           
            % Structure Containing Datafile information
            app.S_FileInfo = struct('Filename',app.edit_filename,'Start_Date',app.edit_start_date,...
            'Start_Time',app.edit_start_time,'End_Time',app.edit_end_time,...
            'Log_Interval',app.edit_logint,'Decay_Time',app.edit_decay, 'Pulse_Power', app.edit_pulse_W,...
            'Pulse_Length',app.edit_pulse_s,...
            'No_Thermistors',app.H_NoThermistors);
           
            % Structure for checkboxes
            app.S_PlotCheckboxes = struct('Accel_check', app.checkbox_accelplot, ...
                'Depth_check', app.checkbox_depthplot, 'Tilt_check', app.checkbox_tiltplot, ...
                'Raw_check', app.checkbox_rawp, 'Rawdec_check', app.checkbox_rawdec, ...
                'Filtered_check', app.checkbox_clnp, 'Filtereddec_check', app.checkbox_decp);
           
            % Structure for edit boxes
            app.S_EditBoxes = struct('StartTime', app.edit_start_time, 'Log',app.edit_logint,...
                'StartDate',app.edit_start_date, 'EndTime',app.edit_end_time, 'Decay', app.edit_decay,...
                'StartCal',app.edit__StartEqm,'EndCal',app.edit_EndEqm,...
                'StartPen',app.edit_PenStart,'HeatPulse',app.edit_HeatPulse,...
                'EndPen',app.edit_End,'PulseTime',app.edit_pulse_s,'PulsePower',app.edit_pulse_W);
           
            % ----- Structure Containing All Previous Structures ----- %
            app.H = struct('Fileinfo',app.S_FileInfo,'Axes',app.S_Axes,...
            'Exe_Controls',app.S_Exe_Controls,...
            'Selections',app.S_Selections,'Selections_Lines',app.S_Selections_Lines,...
            'Plot_Controls',app.S_Plot_Controls,'Axis_Limits',app.S_AxLims,...
               'Error',app.ErrorFlag,'Crop',app.CropFlag, 'PlotCheckboxes',app.S_PlotCheckboxes,...
               'EditBoxes',app.S_EditBoxes, 'VerticalLines', app.S_VerticalLines);
           
            % Reset GUI and initialize program
            % ---------------------------------
            [app.All_Zeros, ...
                   app.All_NaN,...
                   app.Traw,...
                   app.Tcln,...
                   app.Pitch,...
                   app.Roll,...
                   app.G,...
                   app.Tilt,...
                   app.Time,...
                   app.AccelPlot,...
                   app.TiltPlot,...
                   app.DepthPlot,...
                   app.penetrationInfo,...
                   app.HeatPulsePens,...
                   app.PulseData,...
                   app.selTime_Vector,...
                   app.DataType,...
                   app.Params]...
                     = Restart(app.figure_Main,...
                     app.H, ...
                     app.grid_PlotWindow, ...
                     app.checkbox_UseHP,...
                     app.PulseData, ...
                     app.label_currentpathfull, ...
                     app.dropdown_Select);
            
            
        end

        % Button pushed function: button_ResetSelection
        function button_ResetSelectionPushed(app, event)
% ================================================
%% Called when Reset All Events button is pressed.
% =================================================

            % Reset all selections
            % ---------------------
            ResetSelections(app.H, app.dropdown_Select);

        end

        % Value changed function: dropdown_Select
        function dropdown_SelectValueChanged(app, event)
% =========================================================
%% Called event selection drop down menu value is changed.
% =========================================================            
            
%% Call function for selecting events

            [lines] = SelectVerticalLines(app.H,...
                   app.figure_Main, ...
                   app.dropdown_Select);

%% Update program and GUI
            app.H.VerticalLines = lines;
            PVL = [app.H.VerticalLines.Start_Pen_Line app.H.VerticalLines.End_Pen_Line];
    
            % If start and end times for penetration are selected, 
            % enable 'Set Penetration' button
            % --------------------------------------------------
            if all(ishandle(PVL))
                app.button_SetPen.Enable = "on";
            end

        end

        % Button pushed function: button_restart
        function button_restartButtonPushed(app, event)
% ===========================================
%% Called event restart button is pressed.
% ===========================================

%% Call function to restart SlugPen 
            [app.All_Zeros, ...
            app.All_NaN,...
            app.Traw,...
            app.Tcln,...
            app.Pitch,...
            app.Roll,...
            app.G,...
            app.Tilt,...
            app.Time,...
            app.AccelPlot,...
            app.TiltPlot,...
            app.DepthPlot,...
            app.penetrationInfo,...
            app.HeatPulsePens,...
            app.PulseData,...
            app.selTime_Vector,...
            app.DataType,...
            app.Params]...
              = Restart(app.figure_Main,...
              app.H, ...
              app.grid_PlotWindow, ...
              app.checkbox_UseHP,...
              app.PulseData, ...
              app.label_currentpathfull, ...
              app.dropdown_Select);

        end

        % Button pushed function: button_LoadRaw
        function button_LoadRawPushed(app, event)
% =============================================
%% Called when Load Raw Data button is pressed.
% =============================================

%% Reset GUI and initialize program
   [app.All_Zeros, ...
          app.All_NaN,...
          app.Traw,...
          app.Tcln,...
          app.Pitch,...
          app.Roll,...
          app.G,...
          app.Tilt,...
          app.Time,...
          app.AccelPlot,...
          app.TiltPlot,...
          app.DepthPlot,...
          app.penetrationInfo,...
          app.HeatPulsePens,...
          app.PulseData,...
          app.selTime_Vector,...
          app.DataType,...
          app.Params]...
            = Restart(app.figure_Main,...
            app.H, ...
            app.grid_PlotWindow, ...
            app.checkbox_UseHP,...
            app.PulseData, ...
            app.label_currentpathfull, ...
            app.dropdown_Select);


%% Get raw data files
            [f, ...
             app.NoTherm, ...
             app.NoActiveTherm,...
             filename] ...
             = GetFiles(app.figure_Main, ...
              app.H, ...
              app.edit_NumberofTherm);

%% Load raw data in from raw data files            

       % Run Unix function, if the user is using a Unix system
       % ----------------------------------------------------
       if isunix

                [app.Parameters, ...
                  app.dataloaded,...
                  app.parsedtiming,...
                  HSTART,...
                  RPT] ...
                    = LoadUnix(app.figure_Main, ...
                    app.NoTherm, ...
                    f);
              
       % Run Non-Unix function, if the user is using a Non-Unix system
       % --------------------------------------------------------------              
       else

                [app.Parameters,...
                    app.dataloaded,...
                    app.parsedtiming]...
                    = LoadNonUnix(f, ...
                        app.figure_Main);

       end
        
       % Save raw temp data as an app property
       % -------------------------------------
       app.Traw = app.dataloaded.TRAW;

%% Calculate TILT and correct depth and year, if needed
       [app.Tilt, app.G]...
        = TiltCalc(app.dataloaded, ...
        app.parsedtiming);

%% Get datetimes (only unique times)
       [app.datauniquetimes] ...
       = GetDateTime(app.dataloaded,app.parsedtiming, ...
       app.Tilt,app.G);
      
%% Filter data
       [app.Tcln, ...
       app.decimateddata,...
       app.Record] ...
           = Filter(app.figure_Main, ...
           app.edit_MedianFilter,...
           app.edit_MeanFilter,...
           app.edit_DecimateFilter,...
           app.datauniquetimes, ...
           app.Traw, ...
           app.NoTherm);
        
%% Organize data
  
         % Display ACOMMS data - heat pulse and report
         % ---------s---------------------------------
         HPDATA = [HSTART' RPT'];

         % Fold data into structre
         % ------------------------
        
         % Pull data from structure
         timeNumU   =  app.datauniquetimes.TIMENUMU ;
         pwrU       =  app.datauniquetimes.PWRU ;
         tiltU      =  app.datauniquetimes.TILTU ;
         gU         =  app.datauniquetimes.GU ;
         zU         =  app.datauniquetimes.ZU ;
         app.TrawU  =  app.datauniquetimes.TRAWU ;
         accxU      =  app.datauniquetimes.ACCxU ;
         accyU      =  app.datauniquetimes.ACCyU ;
         acczU      =  app.datauniquetimes.ACCzU ;

         app.TdecRaw = app.decimateddata.TDecRaw;
         app.Tdec    = app.decimateddata.TDecFiltered;
         G_dec       = app.decimateddata.GDec;
         Tilt_dec    = app.decimateddata.TiltDec;
         Time_dec    = app.decimateddata.TimeDec;
         Record_dec  = app.decimateddata.RecordDec;
         Power_dec   = app.decimateddata.PowerDec;
         Depth_dec   = app.decimateddata.DepthDec;

         % Generate data structure to be used in subsequent parsing
         % -------------------------------------------------------
         app.DATA = struct('Traw',app.TrawU,'Tcln',app.Tcln, 'TdecRaw', app.TdecRaw, 'Tdec',app.Tdec,...
            'G',gU,'G_dec',G_dec,'Tilt',tiltU,'Tilt_dec',Tilt_dec,...
            'Time',timeNumU,'Time_dec',Time_dec,...
            'Record',app.Record,'Record_dec',Record_dec,...
            'Power',pwrU,'Power_dec',Power_dec,...
            'Depth',zU,'Depth_dec',Depth_dec,...
            'Acc_x',accxU,...
            'Acc_y',accyU,...
            'Acc_z',acczU,...
            'Parameters',{app.Parameters},...
            'Acomms',{HPDATA});

        % Assign file information
        % -----------------------
        [y,m,d] = ymd(app.DATA.Time(1));
        StartDate = regexprep(strjoin(string([y,m,d])), ' ', '-');
        [h,mi,s] = hms(app.DATA.Time(1));
        StartTime = regexprep(strjoin(string([h,mi,s])), ' ', ':');
        [h,mi,s] = hms(app.DATA.Time(end));
        EndTime = regexprep(strjoin(string([h,mi,s])), ' ', ':');
    
        app.H.Fileinfo.Start_Date.Value=StartDate;
        app.H.Fileinfo.Start_Time.Value=StartTime;
        app.H.Fileinfo.End_Time.Value=EndTime;
        app.H.Fileinfo.Filename.Value=filename;
        app.label_Station.Text = filename;
        app.H.Fileinfo.HFID=filename(17:end);
        app.H.Fileinfo.Log_Interval.Value=num2str(app.DATA.Parameters.Value(6));
        app.H.Fileinfo.Decay_Time.Value=num2str(app.DATA.Parameters.Value(5));
        app.H.Fileinfo.Pulse_Length.Value=num2str(app.DATA.Parameters.Value(3));
        app.H.Fileinfo.Pulse_Power.Value=num2str(app.DATA.Parameters.Value(4));
        app.H.Fileinfo.No_Thermistors.Value = app.NoTherm;

        % Extract power of heat pulse in Joules and 
        % Joules/meter from each heat pulse fired
        % ----------------------------------------------
        
        % Get number of heat pulse fires
        [NumofHP, ~] = size(app.DATA.Acomms); 
    
        % Get pulse power for each fire
        PulseRPT = repmat(" ", 1,NumofHP);
        for i = 1:NumofHP
            PulseRPT(i) = string(app.DATA.Acomms{i,2});
            app.PulsePower(i) = str2double(extractAfter(PulseRPT(i), 'J'));
        end

%% Plot raw data
        [app.Checkboxes, ...
         app.TempPlots, ...
         app.BadSensors] = PlotData(app.figure_Main, ...
         app.H, ...
         app.DATA, ...
         app.panel_Thermistors);

%% Update number of usable sensors
    
         % AllSensors is all sensors
         AllSensors = 1:app.NoTherm;
     
         % SensorsToUse includes top water sensor
         app.SensorsToUse=setdiff(AllSensors,app.BadSensors);
     
         % NoActiveTherm does NOT include top water sensor
         app.NoActiveTherm=length(app.SensorsToUse);

%% Update GUI

         figure(app.figure_Main)
         app.H.Axis_Limits.AxLims_Raw = app.H.Axes.Raw.XLim;
      
         % Enable all checkboxes and buttons now that data is loaded in
         % -------------------------------------------------------------
         app.checkbox_SelCal.Enable='on';
         app.checkbox_UseHP.Enable='on';
         app.checkbox_rawdec.Enable='on';
         app.checkbox_decp.Enable='on';
         app.checkbox_tiltplot.Enable='on';
         app.checkbox_clnp.Enable='on';
         app.checkbox_accelplot.Enable='on';
         app.checkbox_rawp.Enable='on';
         app.checkbox_depthplot.Enable='on';
         app.button_RemoveSensors.Enable='on';
         app.button_BeginSelections.Enable='on';
         app.button_ResetSelection.Enable='on';
         app.button__ResetPen.Enable='on';
    

        end

        % Button pushed function: button_BeginSelections
        function button_BeginSelectionsButtonPushed(app, event)
% =============================================
%% Called when Load Raw Data button is pressed.
% =============================================

%% Allow user to select timing of events and plot vertical lines 

         [lines] = SelectVerticalLines(app.H,...
            app.figure_Main, ...
            app.dropdown_Select);
            app.H.VerticalLines = lines;
            
        end

        % Value changed function: checkbox_decp
        function checkbox_decpValueChanged(app, event)
% =============================================
%% Called when decimated data checkbox is changed.
% =============================================
        
% Get value
        DecValue = app.checkbox_decp.Value;

% If unchecked, turn off decimated plots        
        if DecValue == 0
            for i=app.SensorsToUse
                set(app.H.Plot_Controls.DecimatedPlot.UserData(i),'Visible','off');
            end
% If checked, turn on decimated plots            
        else
            for i=app.SensorsToUse
                set(app.H.Plot_Controls.DecimatedPlot.UserData(i),'Visible','on');
            end

            for i=app.SensorsToUse
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeRawDec;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeRaw;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeFiltered;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeFilteredDec;
                app.Checkboxes{i}.Value = 1;
            end
        end

        end

        % Value changed function: checkbox_clnp
        function checkbox_clnpValueChanged(app, event)
% =============================================
%% Called when filtered data checkbox is changed.
% =============================================
        
% Get value        
        CleanValue = app.checkbox_clnp.Value;

% If unchecked, turn off decimated plots  
        if CleanValue == 0
            for i=app.SensorsToUse
                set(app.H.Plot_Controls.CleanPlot.UserData(i),'Visible','off');
            end
% If checked, turn on decimated plots              
        else
            for i=app.SensorsToUse
                set(app.H.Plot_Controls.CleanPlot.UserData(i),'Visible','on');
            end

            for i=app.SensorsToUse
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeRawDec;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeRaw;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeFiltered;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeFilteredDec;
                app.Checkboxes{i}.Value = 1;
            end
        end

        end

        % Value changed function: checkbox_rawp
        function checkbox_rawpValueChanged(app, event)
% =============================================
%% Called when raw data checkbox is changed.
% =============================================
        
% Get value        
        RawValue = app.checkbox_rawp.Value;

% If unchecked, turn off decimated plots         
        if RawValue == 0
            for i=app.SensorsToUse
                set(app.H.Plot_Controls.RawPlot.UserData(i),'Visible','off');
            end
% If checked, turn on decimated plots 
        else
            for i=app.SensorsToUse
                set(app.H.Plot_Controls.RawPlot.UserData(i),'Visible','on');
            end
            
            for i=app.SensorsToUse
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeRawDec;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeRaw;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeFiltered;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeFilteredDec;
                app.Checkboxes{i}.Value = 1;
            end
        end

        end

        % Button pushed function: button_SetPen
        function button_SetPenPushed(app, event)
% ===============================================
%% Called when Set Penetration button is pressed.
% ===============================================

% Get data from start to end of penetration 

        PVL = [app.H.VerticalLines.Start_Pen_Line app.H.VerticalLines.End_Pen_Line];

% If not all required events have been defined by the user, do 
% not allow penetration to be set
        if ~all(ishandle(PVL))
            app.button_SetPen.Enable = "off";
            return
        end

% Set input values 
       if app.DataType < 3
            Depth = app.DATA.Depth;
            Depth = Depth';
            time = app.DATA.Time;
            tilt = app.DATA.Tilt;
       else
            Depth = app.DATA.Depth_dec;
            Depth = Depth';
            time = app.DATA.Time_dec;
            tilt = app.DATA.Tilt_dec;   
       end 
         
% Define penetration measurement Start and End %
       msmt_start = datetime(app.H.Selections.Start_Pen.Value);
       msmt_end = datetime(app.H.Selections.End_Pen.Value);

% Find mean depth and tilt records within measurement period
       a = find(time>=msmt_start & time<=msmt_end);
       if ~isempty(a)
           pen_depth = mean(Depth(a), 'omitnan');
           pen_tilt = mean(tilt(a), 'omitnan');
           pen_tilt = sprintf('%2.1f', pen_tilt);
           pen_tilt = str2double(pen_tilt);
       else
           uialert(app.figure_Main, ['No Data in Time Window. ' ...
               'Please assign start and end of penetration times.'], 'Error',  'Icon','error')
           return
       end
        
% Chenge missing data to -999
        
        % Depth
        if pen_depth==0
            pen_depth=-999;
        end

        % Tilt
        if pen_tilt==0
            pen_tilt=-999;
        end
          
%% Create penfile input auxiallary app
        app.SetPenInfoApp = SetPenInfo(app, app.NoTherm, ...
            app.NoActiveTherm, app.SensorsToUse,pen_tilt, pen_depth);
        app.PenSet = 1;


        end

        % Value changed function: edit__StartEqm
        function edit__StartEqmValueChanged(app, event)
% ================================================================
%% Called when start of calibration period edit field is changed.
% ================================================================

% Update data and plot to refelect start of calibration time 
% defined in the edit field
         value = str2double(app.edit__StartEqm.Value);
         app.H.VerticalLines.Start_Eqm_Line = xline(app.axis_raw, value);
         app.H.VerticalLines.Start_Eqm_Line.Label = "Start Calibration Period (C1)";
         app.H.VerticalLines.Start_Eqm_Line.LabelHorizontalAlignment = 'center';

        end

        % Value changed function: checkbox_depthplot
        function checkbox_depthplotValueChanged(app, event)
% ================================================================
%% Called when depth plot checkbox is pressed.
% ================================================================

% Get value
        app.DepthPlot= app.checkbox_depthplot.Value;
            
% Adjust grid layout for plots
         plotLayout(app.H,...
                 app.DepthPlot, ...
                 app.AccelPlot, ...
                 app.TiltPlot, ...
                 app.grid_PlotWindow)

% Align axes
         xAlign(app.axis_raw, app.axis_depth, app.axis_accel, app.axis_tilt)

        end

        % Value changed function: checkbox_accelplot
        function checkbox_accelplotValueChanged(app, event)
% ================================================================
%% Called when acceleration plot checkbox is pressed.
% ================================================================

% Get value           
        app.AccelPlot = app.checkbox_accelplot.Value;
        
% Adjust grid layout for plots
        plotLayout(app.H,...
                app.DepthPlot, ...
                app.AccelPlot, ...
                app.TiltPlot, ...
                app.grid_PlotWindow)

% Align axes
        xAlign(app.axis_raw, app.axis_depth, app.axis_accel, app.axis_tilt)

        end

        % Value changed function: checkbox_tiltplot
        function checkbox_tiltplotValueChanged(app, event)
% ================================================================
%% Called when tilt plot checkbox is pressed.
% ================================================================

% Get value             
        app.TiltPlot = app.checkbox_tiltplot.Value;
            
% Adjust grid layout for plots
         plotLayout(app.H,...
                 app.DepthPlot, ...
                 app.AccelPlot, ...
                 app.TiltPlot, ...
                 app.grid_PlotWindow)

% Align axes
         xAlign(app.axis_raw, app.axis_depth, app.axis_accel, app.axis_tilt)

        end

        % Button pushed function: button__ResetPen
        function button__ResetPenPushed(app, event)
% ==================================================
%% Called when reset penetration button is pressed.
% ==================================================

            % Reset all selections
            % ---------------------
            ResetPen(app.H, app.dropdown_Select);

        end

        % Button pushed function: button_CommandWindowArrow
        function button_CommandWindowArrowPushed(app, event)
% ==================================================
%% Called when command window open button is pressed.
% ==================================================

% Update grid layout to show command window
        app.grid_MainFigure.ColumnWidth = {'1x', '0x', '8x'};
            
        end

        % Button pushed function: button_RemoveSensors
        function button_RemoveSensorsPushed(app, event)
% ==================================================
%% Called when Remove Sensors button is pressed.
% ==================================================

% If checkbox is unchecked, make all temps for this thermistor NaN
% -----------------------------------------------------------------
            Bad = repmat([], length(app.NoTherm),1);
            for i =1:app.NoTherm
                if app.Checkboxes{i}.Value == 0
                    Bad(i) = i;
                end
            end
            Bad(Bad==0) = [];
  
% Warn user before removing data
% ------------------------------------
        confirmation = uiconfirm(app.figure_Main, ['Recordings from all unchecked ' ...
            'sensors will be discarded and REMOVED from ' ...
            'data.' newline newline ...
            'Sensors discarded ' newline int2str(Bad)], ...
            'Confirm sensor discard', 'Icon', 'warning');
        switch confirmation

% If user confirms the go ahead, remove all data from unchecked sensors     
% ------------------------------------------------------------------------
        case 'OK'
            for i =1:app.NoTherm
                if app.Checkboxes{i}.Value == 0
                    app.DATA.Tdec(i,:) = -999;
                    app.DATA.Tcln(i,:) = -999;
                    app.DATA.Traw(i,:) = -999;
                    app.DATA.TdecRaw(i,:) = -999;
                    app.Checkboxes{i}.Enable = 0;
                end
            end
            
        end

% SensorsToUse (vector of all sensors used) and NoActiveTherm 
% (total number of active sensors) include top water sensor
        app.SensorsToUse = setdiff(app.SensorsToUse, Bad);
        app.NoActiveTherm = length(app.SensorsToUse);

        end

        % Value changed function: checkbox_UseHP
        function checkbox_UseHPValueChanged(app, event)
% ==================================================
%% Called when Use HP checkbox value is changed.
% ==================================================    

% Define whether to use the heat pulse or not based on this checkbox
            Pulse = app.checkbox_UseHP.Value;

% If using a pulse, allow user to choose timing of pulse            
            if Pulse == 1
                app.PulseData = 1;
                app.edit_HeatPulse.Enable="on";
                if app.checkbox_SelCal.Value==1
                    app.dropdown_Select.Items={'Start Calibration Period (C1)',...
                            'End Calibration Period (C2)',...
                            'Start Penetration (P)',...
                            'End Penetration (E)',...
                            'Heat Pulse (H)'};
                else
                    app.dropdown_Select.Items={
                            'Start Penetration (P)',...
                            'End Penetration (E)',...
                            'Heat Pulse (H)'};
                end
% If NOT using a pulse, do NOT allow user to choose timing of pulse                
            elseif Pulse == 0
                app.PulseData = 0;
                app.edit_HeatPulse.Enable="off";
                if app.checkbox_SelCal.Value==1
                    app.dropdown_Select.Items={'Start Calibration Period (C1)',...
                            'End Calibration Period (C2)',...
                            'Start Penetration (P)',...
                            'End Penetration (E)'};
                else
                    app.dropdown_Select.Items={
                            'Start Penetration (P)',...
                            'End Penetration (E)'};
                end
                delete(app.H.VerticalLines.Start_Heat_Line);
                delete(app.H.VerticalLines.HP_depth)
                delete(app.H.VerticalLines.HP_accel)
                delete(app.H.VerticalLines.HP_tilt)
            end

        end

        % Value changed function: checkbox_rawdec
        function checkbox_rawdecValueChanged(app, event)
% =========================================================
%% Called when raw decimated data checkbox value is changed.
% =========================================================  

% Get value
        RawDecValue = app.checkbox_rawdec.Value;

% If raw decimated data is off, turn off plots
        if RawDecValue == 0
            for i=app.SensorsToUse
                set(app.H.Plot_Controls.RawDecimatedPlot.UserData(i),'Visible','off');
            end
% If raw decimated data is on, turn on plots            
        else
            for i=app.SensorsToUse
                set(app.H.Plot_Controls.RawDecimatedPlot.UserData(i),'Visible','on');
            end

            for i=app.SensorsToUse
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeRawDec;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeRaw;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeFiltered;
                app.Checkboxes{i}.ValueChangedFcn{1} = @cbSensValueChangeFilteredDec;
                app.Checkboxes{i}.Value = 1;
            end
        end
        
        end

        % Value changed function: checkbox_SelCal
        function checkbox_SelCalValueChanged(app, event)
% =====================================================================
%% Called when calibration period selection checkbox value is changed.
% =====================================================================  

% Define whether to select a calibration period or not based on this checkbox            
            SelCal = app.checkbox_SelCal.Value;

% If selecting a calibration period, allow user to choose timing of calibration period               
            if SelCal == 1
                app.SelCalData = 1;
                app.dropdown_Select.Items={'Start Calibration Period (C1)',...
                                            'End Calibration Period (C2)',...
                                            'Start Penetration (P)',...
                                            'End Penetration (E)',...
                                            'Heat Pulse (H)'};
                app.edit__StartEqm.Enable="on";
                app.edit_EndEqm.Enable="on";

% If NOT selecting a calibration period, do NOT allow user to choose timing of calibration period         
            elseif SelCal == 0
                app.SelCalData = 0;
                if app.checkbox_UseHP.Value==1
                        app.dropdown_Select.Items={'Start Penetration (P)',...
                            'End Penetration (E)',...
                            'Heat Pulse (H)'};
                else
                    app.dropdown_Select.Items={'Start Penetration (P)',...
                            'End Penetration (E)'};
                end
                app.edit__StartEqm.Enable="off";
                app.edit_EndEqm.Enable="off";
                delete(app.H.VerticalLines.Start_Eqm_Line);
                delete(app.H.VerticalLines.End_Eqm_Line);
                delete(app.H.VerticalLines.SE_depth)
                delete(app.H.VerticalLines.SE_accel)
                delete(app.H.VerticalLines.SE_tilt)
                delete(app.H.VerticalLines.EE_depth)
                delete(app.H.VerticalLines.EE_accel)
                delete(app.H.VerticalLines.EE_tilt)
            end
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure_Main = uifigure('Visible', 'off');
            app.figure_Main.Name = 'SlugPen';
            app.grid_MainFigure = uigridlayout(app.figure_Main);
            app.panel_Data = uipanel(app.grid_MainFigure);
            app.grid_data = uigridlayout(app.panel_Data);
            app.panel_SlugPen = uipanel(app.grid_data);
            app.grid_SlugPen = uigridlayout(app.panel_SlugPen);
            app.button_quit = uibutton(app.grid_SlugPen, 'push');
            app.button_quit.Icon = fullfile(pathToMLAPP, 'Images', 'x.png');
            app.button_quit.Tooltip = {'Hide command window'};
            app.button_quit.Text = '';
            app.button_restart = uibutton(app.grid_SlugPen, 'push');
            app.button_restart.ButtonPushedFcn = createCallbackFcn(app, @button_restartButtonPushed, true);
            app.button_restart.Tooltip = {'Restart program and remove all loaded data'};
            app.button_restart.Text = 'Restart';
            app.image_SlugPen = uiimage(app.grid_SlugPen);
            app.image_SlugPen.ImageSource = fullfile(pathToMLAPP, 'Images', 'SlugPenLogo.png');
            app.grid_UCSCimage = uigridlayout(app.grid_SlugPen);
            app.image_UCSC = uiimage(app.grid_UCSCimage);
            app.image_UCSC.ImageSource = fullfile(pathToMLAPP, 'images', 'UCSC-HydrogeologyLogo.jpg');
            app.panel_plotcontrols = uipanel(app.grid_data);
            app.panel_plotcontrols.Title = 'Plot Controls';
            app.grid_plotcontrols = uigridlayout(app.panel_plotcontrols);
            app.label_plotscheckboxes = uilabel(app.grid_plotcontrols);
            app.label_plotscheckboxes.Text = 'Plots:';
            app.label_datacheckboxes = uilabel(app.grid_plotcontrols);
            app.label_datacheckboxes.Text = 'Data:';
            app.checkbox_depthplot = uicheckbox(app.grid_plotcontrols);
            app.checkbox_depthplot.ValueChangedFcn = createCallbackFcn(app, @checkbox_depthplotValueChanged, true);
            app.checkbox_depthplot.Enable = 'off';
            app.checkbox_depthplot.Text = 'Depth';
            app.checkbox_depthplot.Value = true;
            app.checkbox_rawp = uicheckbox(app.grid_plotcontrols);
            app.checkbox_rawp.ValueChangedFcn = createCallbackFcn(app, @checkbox_rawpValueChanged, true);
            app.checkbox_rawp.Enable = 'off';
            app.checkbox_rawp.Text = 'Raw';
            app.checkbox_rawp.Value = true;
            app.checkbox_accelplot = uicheckbox(app.grid_plotcontrols);
            app.checkbox_accelplot.ValueChangedFcn = createCallbackFcn(app, @checkbox_accelplotValueChanged, true);
            app.checkbox_accelplot.Enable = 'off';
            app.checkbox_accelplot.Text = 'Accel.';
            app.checkbox_clnp = uicheckbox(app.grid_plotcontrols);
            app.checkbox_clnp.ValueChangedFcn = createCallbackFcn(app, @checkbox_clnpValueChanged, true);
            app.checkbox_clnp.Enable = 'off';
            app.checkbox_clnp.Text = 'Filtered';
            app.checkbox_tiltplot = uicheckbox(app.grid_plotcontrols);
            app.checkbox_tiltplot.ValueChangedFcn = createCallbackFcn(app, @checkbox_tiltplotValueChanged, true);
            app.checkbox_tiltplot.Enable = 'off';
            app.checkbox_tiltplot.Text = 'Tilt';
            app.checkbox_tiltplot.Value = true;
            app.checkbox_decp = uicheckbox(app.grid_plotcontrols);
            app.checkbox_decp.ValueChangedFcn = createCallbackFcn(app, @checkbox_decpValueChanged, true);
            app.checkbox_decp.Enable = 'off';
            app.checkbox_decp.Text = 'Filtered dec';
            app.checkbox_rawdec = uicheckbox(app.grid_plotcontrols);
            app.checkbox_rawdec.ValueChangedFcn = createCallbackFcn(app, @checkbox_rawdecValueChanged, true);
            app.checkbox_rawdec.Enable = 'off';
            app.checkbox_rawdec.Text = 'Raw dec';
            app.checkbox_rawdec.Value = true;
            app.panel_selected = uipanel(app.grid_data);
            app.panel_selected.Title = 'Event Selections ';
            app.grid_selected = uigridlayout(app.panel_selected);
            app.dropdown_Select = uidropdown(app.grid_selected);
            app.dropdown_Select.Items = {'Start Calibration Period (C1)', 'End Calibration Period (C2)', 'Start Penetration (P)', 'End Penetration (E)', 'Heat Pulse (H)'};
            app.dropdown_Select.ValueChangedFcn = createCallbackFcn(app, @dropdown_SelectValueChanged, true);
            app.dropdown_Select.Enable = 'off';
            app.dropdown_Select.Tooltip = {'Click to graphically select data for creating penfile'};
            app.dropdown_Select.Value = 'Start Calibration Period (C1)';
            app.button_ResetSelection = uibutton(app.grid_selected, 'push');
            app.button_ResetSelection.ButtonPushedFcn = createCallbackFcn(app, @button_ResetSelectionPushed, true);
            app.button_ResetSelection.Enable = 'off';
            app.button_ResetSelection.Tooltip = {'Reset timing of all events, including penetration events and thermistor calibration period'};
            app.button_ResetSelection.Text = 'Reset All Events';
            app.button_BeginSelections = uibutton(app.grid_selected, 'push');
            app.button_BeginSelections.ButtonPushedFcn = createCallbackFcn(app, @button_BeginSelectionsButtonPushed, true);
            app.button_BeginSelections.Enable = 'off';
            app.button_BeginSelections.Tooltip = {'Begin selection of events'};
            app.button_BeginSelections.Text = 'Select Event';
            app.button__ResetPen = uibutton(app.grid_selected, 'push');
            app.button__ResetPen.ButtonPushedFcn = createCallbackFcn(app, @button__ResetPenPushed, true);
            app.button__ResetPen.Enable = 'off';
            app.button__ResetPen.Tooltip = {'Reset timing of only penetration events (i.e., start and end of penetration; heat pulse firing)'};
            app.button__ResetPen.Text = 'Reset Penetration Events';
            app.checkbox_UseHP = uicheckbox(app.grid_selected);
            app.checkbox_UseHP.ValueChangedFcn = createCallbackFcn(app, @checkbox_UseHPValueChanged, true);
            app.checkbox_UseHP.Enable = 'off';
            app.checkbox_UseHP.Tooltip = {'When on, the timing of a heat pulse must be selected. When off, a heat pulse does not need to be selected'};
            app.checkbox_UseHP.Text = 'Use heat pulse ';
            app.checkbox_UseHP.Value = true;
            app.StartCalibrationPeriodW1Label = uilabel(app.grid_selected);
            app.StartCalibrationPeriodW1Label.Text = 'Start Cal. Period (W1): ';
            app.edit__StartEqm = uieditfield(app.grid_selected, 'text');
            app.edit__StartEqm.CharacterLimits = [0 20];
            app.edit__StartEqm.ValueChangedFcn = createCallbackFcn(app, @edit__StartEqmValueChanged, true);
            app.edit__StartEqm.Editable = 'off';
            app.edit__StartEqm.Enable = 'off';
            app.edit__StartEqm.Tooltip = {'Time of the start of the thermistor calibration period'};
            app.EndCalibrationPeriodW2Label = uilabel(app.grid_selected);
            app.EndCalibrationPeriodW2Label.Text = 'End Cal. Period (W2): ';
            app.edit_EndEqm = uieditfield(app.grid_selected, 'text');
            app.edit_EndEqm.CharacterLimits = [0 20];
            app.edit_EndEqm.Editable = 'off';
            app.edit_EndEqm.Enable = 'off';
            app.edit_EndEqm.Tooltip = {'Time of the end of the thermistor calibration period'};
            app.StartPenetrationPEditFieldLabel = uilabel(app.grid_selected);
            app.StartPenetrationPEditFieldLabel.Text = 'Start Penetration (P): ';
            app.edit_PenStart = uieditfield(app.grid_selected, 'text');
            app.edit_PenStart.CharacterLimits = [0 20];
            app.edit_PenStart.Editable = 'off';
            app.edit_PenStart.Enable = 'off';
            app.edit_PenStart.Tooltip = {'Time of the start of the instrument penetration'};
            app.EndPenetrationEEditFieldLabel = uilabel(app.grid_selected);
            app.EndPenetrationEEditFieldLabel.Text = 'End Penetration (E): ';
            app.edit_End = uieditfield(app.grid_selected, 'text');
            app.edit_End.CharacterLimits = [0 20];
            app.edit_End.Editable = 'off';
            app.edit_End.Enable = 'off';
            app.edit_End.Tooltip = {'Time of the end of the instrument penetration'};
            app.HeatPulseHEditFieldLabel = uilabel(app.grid_selected);
            app.HeatPulseHEditFieldLabel.Text = 'Heat Pulse (H): ';
            app.edit_HeatPulse = uieditfield(app.grid_selected, 'text');
            app.edit_HeatPulse.CharacterLimits = [0 20];
            app.edit_HeatPulse.Editable = 'off';
            app.edit_HeatPulse.Enable = 'off';
            app.edit_HeatPulse.Tooltip = {'Time of the heat pule firing'};
            app.checkbox_SelCal = uicheckbox(app.grid_selected);
            app.checkbox_SelCal.ValueChangedFcn = createCallbackFcn(app, @checkbox_SelCalValueChanged, true);
            app.checkbox_SelCal.Enable = 'off';
            app.checkbox_SelCal.Tooltip = {'When on, the calibration period must be selected before a .pen file can be created. When off, calibration period does not need to be selected'};
            app.checkbox_SelCal.Text = 'Select calibration period';
            app.checkbox_SelCal.Value = true;
            app.button_SetPen = uibutton(app.grid_selected, 'push');
            app.button_SetPen.ButtonPushedFcn = createCallbackFcn(app, @button_SetPenPushed, true);
            app.button_SetPen.Enable = 'off';
            app.button_SetPen.Tooltip = {'Create a .pen file using the selected timings'};
            app.button_SetPen.Text = 'Create .pen file';
            app.panel_info = uipanel(app.grid_data);
            app.panel_info.Title = 'Raw Data';
            app.grid_datainfo = uigridlayout(app.panel_info);
            app.FilenameLabel = uilabel(app.grid_datainfo);
            app.FilenameLabel.Text = 'Raw data file:';
            app.edit_filename = uieditfield(app.grid_datainfo, 'text');
            app.edit_filename.Enable = 'off';
            app.edit_filename.Tooltip = {'Filename for .raw data file'};
            app.StartDateEditFieldLabel = uilabel(app.grid_datainfo);
            app.StartDateEditFieldLabel.Text = 'Start Date:';
            app.edit_start_date = uieditfield(app.grid_datainfo, 'text');
            app.edit_start_date.Enable = 'off';
            app.edit_start_date.Tooltip = {'Date of start of instrument deployment'};
            app.StartTimeEditFieldLabel = uilabel(app.grid_datainfo);
            app.StartTimeEditFieldLabel.Text = 'Start Time:';
            app.edit_start_time = uieditfield(app.grid_datainfo, 'text');
            app.edit_start_time.Enable = 'off';
            app.edit_start_time.Tooltip = {'Time of start of instrument deployment'};
            app.EndTimeEditFieldLabel = uilabel(app.grid_datainfo);
            app.EndTimeEditFieldLabel.Text = 'End Time:';
            app.edit_end_time = uieditfield(app.grid_datainfo, 'text');
            app.edit_end_time.Enable = 'off';
            app.edit_end_time.Tooltip = {'Time of end of instrument deployment'};
            app.LogIntervalsLabel = uilabel(app.grid_datainfo);
            app.LogIntervalsLabel.Text = 'Log Interval (s):';
            app.edit_logint = uieditfield(app.grid_datainfo, 'text');
            app.edit_logint.Enable = 'off';
            app.PulsesecEditFieldLabel = uilabel(app.grid_datainfo);
            app.PulsesecEditFieldLabel.Text = 'Pulse (sec): ';
            app.edit_pulse_s = uieditfield(app.grid_datainfo, 'text');
            app.edit_pulse_s.Enable = 'off';
            app.edit_pulse_s.Tooltip = {'Duration of heat pulse'};
            app.DecayminEditFieldLabel = uilabel(app.grid_datainfo);
            app.DecayminEditFieldLabel.Text = 'Decay (min):';
            app.edit_decay = uieditfield(app.grid_datainfo, 'text');
            app.edit_decay.Enable = 'off';
            app.edit_decay.Tooltip = {'Duration of heat pulse decay'};
            app.PulseWLabel = uilabel(app.grid_datainfo);
            app.PulseWLabel.Text = 'Pulse Power (W): ';
            app.edit_pulse_W = uieditfield(app.grid_datainfo, 'text');
            app.edit_pulse_W.Enable = 'off';
            app.edit_pulse_W.Tooltip = {'Power of heat pulse'};
            app.button_LoadRaw = uibutton(app.grid_datainfo, 'push');
            app.button_LoadRaw.ButtonPushedFcn = createCallbackFcn(app, @button_LoadRawPushed, true);
            app.button_LoadRaw.Tooltip = {'Load raw data from a .raw text file'};
            app.button_LoadRaw.Text = 'Load Raw Data';
            app.NumberofThermistorsLabel = uilabel(app.grid_datainfo);
            app.NumberofThermistorsLabel.Text = 'Number of Thermistors:';
            app.edit_NumberofTherm = uieditfield(app.grid_datainfo, 'text');
            app.edit_NumberofTherm.Enable = 'off';
            app.edit_NumberofTherm.Tooltip = {'Number of thermistors used'};
            app.label_FilterWinfowLength = uilabel(app.grid_datainfo);
            app.label_FilterWinfowLength.Enable = 'off';
            app.label_FilterWinfowLength.Text = 'Filter Window Lengths:';
            app.edit_MedianFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_MedianFilter.Value = 2;
            app.edit_MeanFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_MeanFilter.Value = 6;
            app.edit_DecimateFilter = uieditfield(app.grid_datainfo, 'numeric');
            app.edit_DecimateFilter.Value = 10;
            app.panel_PlotWindow = uipanel(app.grid_MainFigure);
            app.grid_PlotWindow = uigridlayout(app.panel_PlotWindow);
            app.axis_tilt = uiaxes(app.grid_PlotWindow);
            app.axis_accel = uiaxes(app.grid_PlotWindow);
            app.axis_accel.Visible = 'off';
            app.axis_depth = uiaxes(app.grid_PlotWindow);
            app.axis_depth.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.axis_raw = uiaxes(app.grid_PlotWindow);
            title(app.axis_raw, 'Time Series')
            app.label_Time = uilabel(app.grid_PlotWindow);
            app.label_Time.Text = 'Time (hr:min)';
            app.panel_TopLabels = uipanel(app.grid_PlotWindow);
            app.grid_TopLabels = uigridlayout(app.panel_TopLabels);
            app.label_SlugHeat = uilabel(app.grid_TopLabels);
            app.label_SlugHeat.Text = 'SlugPen';
            app.label_CurrentPath = uilabel(app.grid_TopLabels);
            app.label_CurrentPath.Text = 'Current Path:';
            app.label_currentpathfull = uilabel(app.grid_TopLabels);
            app.label_currentpathfull.Text = '';
            app.panel_Top = uipanel(app.grid_PlotWindow);
            app.panel_ProcessingInfoLabels = uipanel(app.grid_PlotWindow);
            app.grid_ProcessingInfoLabels = uigridlayout(app.panel_ProcessingInfoLabels);
            app.edit_StationTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_StationTitle.Text = 'Station: ';
            app.edit_PenetrationTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_PenetrationTitle.Text = 'Penetration:';
            app.edit_LatitudeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_LatitudeTitle.Text = 'Latitude:';
            app.edit_LongitudeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_LongitudeTitle.Text = 'Longitude:';
            app.edit_ProcessingTimeTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_ProcessingTimeTitle.Text = 'Processing Time:';
            app.edit_CruiseTitle = uilabel(app.grid_ProcessingInfoLabels);
            app.edit_CruiseTitle.Text = 'Cuise:';
            app.label_Cruise = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Cruise.Text = 'cruise name';
            app.label_Station = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Station.Text = 'station name';
            app.label_Penetration = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Penetration.Text = 'penetration name';
            app.label_Latitude = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Latitude.Text = 'latitude';
            app.label_Longitude = uilabel(app.grid_ProcessingInfoLabels);
            app.label_Longitude.Text = 'longitude';
            app.label_ProcessingTime = uilabel(app.grid_ProcessingInfoLabels);
            app.label_ProcessingTime.Text = 'start of processing';
            app.panel_Thermistors = uipanel(app.grid_PlotWindow);
            app.panel_Thermistors.Title = 'Thermistors';
            app.image_templabel = uiimage(app.grid_PlotWindow);
            app.image_templabel.ImageSource = fullfile(pathToMLAPP, 'images', 'temp_label.jpg');
            app.image_depthlabel = uiimage(app.grid_PlotWindow);
            app.image_depthlabel.ImageSource = fullfile(pathToMLAPP, 'images', 'depth_label.jpg');
            app.image_accellabel = uiimage(app.grid_PlotWindow);
            app.image_accellabel.ImageSource = fullfile(pathToMLAPP, 'images', 'accel_label.jpg');
            app.image_tiltlabel = uiimage(app.grid_PlotWindow);
            app.image_tiltlabel.ImageSource = fullfile(pathToMLAPP, 'images', 'tilt_label.jpg');
            app.grid_DiscardSensors = uigridlayout(app.grid_PlotWindow);
            app.button_RemoveSensors = uibutton(app.grid_DiscardSensors, 'push');
            app.button_RemoveSensors.ButtonPushedFcn = createCallbackFcn(app, @button_RemoveSensorsPushed, true);
            app.button_RemoveSensors.Enable = 'off';
            app.button_RemoveSensors.Text = 'Remove Sensors';
            app.button_CommandWindowArrow = uibutton(app.grid_MainFigure, 'push');
            app.button_CommandWindowArrow.ButtonPushedFcn = createCallbackFcn(app, @button_CommandWindowArrowPushed, true);
            app.button_CommandWindowArrow.Icon = fullfile(pathToMLAPP, 'Images', 'arrowpen.png');
            app.button_CommandWindowArrow.Text = '';
            % Show the figure after all components are created
            app.figure_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SlugPen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure_Main)
        end
    end
end